classdef ConvergenceTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ExponentialButton  matlab.ui.control.Button
        UniformButton      matlab.ui.control.Button
        NormalButton       matlab.ui.control.Button
        distribution       matlab.ui.control.UIAxes
        Data               matlab.ui.control.UIAxes
        convergence        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ExponentialButton
        function ExponentialButtonPushed(app, event)
        a = 1;
        b = 2000;
        [T, count1, count2, error, count3] = convergence_expo();
        plot(app.Data,a:1:b,count1(a:1:b),a:1:1000,count2(a:1:1000),a:1:b,error(a:1:b),'magenta')
         plot(app.distribution,0:0.00001:2,count3,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[0 2]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'AS Conv','Conv in Prob','MS Conv')
        end

        % Callback function
        function GenerateButtonPushed(app, event)


        end

        % Button pushed function: NormalButton
        function NormalButtonPushed(app, event)
        a = 1;
        b = 2000;
        [T, count1, count2, error, count3] = convergence_norm();
        plot(app.Data,a:1:b,count1(a:1:b),a:1:1000,count2(a:1:1000),a:1:b,error(a:1:b),'magenta')
        plot(app.distribution, -1 :0.00001:1,count3,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[-1 1]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'AS Conv','Conv in Prob','MS Conv')
        end

        % Button pushed function: UniformButton
        function UniformButtonPushed(app, event)
        a = 1;
        b = 2000;
        [T, count1, count2, error, count3] = convergence_uni();
        plot(app.Data,a:1:b,count1(a:1:b),a:1:1000,count2(a:1:1000),a:1:b,error(a:1:b),'magenta')
         plot(app.distribution,-1:0.00001:1,count3,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[0 1]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'AS Conv','Conv in Prob','MS Conv')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.convergence = uiaxes(app.UIFigure);
            title(app.convergence, 'Realisations of Y')
            xlabel(app.convergence, 'Number of samples (n)')
            ylabel(app.convergence, '|X(n,w) - X(w)|')
            app.Data = uiaxes(app.UIFigure);
            title(app.Data, 'Convergence')
            xlabel(app.Data, 'Number of samples (n)')
            ylabel(app.Data, 'p(n)   a(n)   en')
            app.distribution = uiaxes(app.UIFigure);
            title(app.distribution, 'Convergence in Distribution')
            xlabel(app.distribution, 'Number of samples (n)')
            ylabel(app.distribution, 'F(y)')
            app.NormalButton = uibutton(app.UIFigure, 'push');
            app.NormalButton.ButtonPushedFcn = createCallbackFcn(app, @NormalButtonPushed, true);
            app.NormalButton.Text = 'Normal';
            app.UniformButton = uibutton(app.UIFigure, 'push');
            app.UniformButton.ButtonPushedFcn = createCallbackFcn(app, @UniformButtonPushed, true);
            app.UniformButton.Text = 'Uniform';
            app.ExponentialButton = uibutton(app.UIFigure, 'push');
            app.ExponentialButton.ButtonPushedFcn = createCallbackFcn(app, @ExponentialButtonPushed, true);
            app.ExponentialButton.Text = 'Exponential';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConvergenceTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end